classdef PsthExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TTLEditField                  matlab.ui.control.EditField
        TTLEditFieldLabel             matlab.ui.control.Label
        AnimalIDEditField             matlab.ui.control.EditField
        AnimalIDEditFieldLabel        matlab.ui.control.Label
        mainFolderEditField           matlab.ui.control.EditField
        mainFolderEditFieldLabel      matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        ZscoretestBinsEditField       matlab.ui.control.NumericEditField
        ZscoretestBinsEditFieldLabel  matlab.ui.control.Label
        Wcx_win2EditField             matlab.ui.control.NumericEditField
        Wcx_win2EditFieldLabel        matlab.ui.control.Label
        Wcx_win1EditField             matlab.ui.control.NumericEditField
        Wcx_win1EditFieldLabel        matlab.ui.control.Label
        PlottingmethodDropDown        matlab.ui.control.DropDown
        PlottingmethodDropDownLabel   matlab.ui.control.Label
        SamplingrateEditField         matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel    matlab.ui.control.Label
        PlotaveragesCheckBox          matlab.ui.control.CheckBox
        NormalisedataCheckBox         matlab.ui.control.CheckBox
        psth_binEditField             matlab.ui.control.NumericEditField
        psth_binEditFieldLabel        matlab.ui.control.Label
        Int2EditField                 matlab.ui.control.NumericEditField
        Int2EditFieldLabel            matlab.ui.control.Label
        Int1EditField                 matlab.ui.control.NumericEditField
        Int1EditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        preferences = struct('mainFolder', 'Z:\HajosLab\Dani\Magyar_Daniel\experiments\PFC_layers\Chrimson_stGtACR\2021_december\Kilosort_v2', ...
            'fs', 30000, ...
            'int', [-5 5], ...
            'norm', 1, ...
            'psth_bin', 6000, ...
            'average', 1, ...
            'plottingMethod', 'Zscore', ...
            'Wcx_win', [-5 2], ...
            'testBins', 10, ...
            'PCA_num', 3, ...
            'clustnum', 3, ...
            'LKmethod', 'complete', ...
            'LKmetric', 'mahalanobis');
        animalID = {'MD098','MD099', 'MD101', 'MD108'};
        TTL = 'BA_25_5Hz'
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % assignin('base', 'preferences', app.preferences)
            master_PsthExplorer(app.animalID, app.TTL, app.preferences)
        end

        % Value changed function: mainFolderEditField
        function mainFolderEditFieldValueChanged(app, event)
            app.preferences.mainFolder = app.mainFolderEditField.Value;          
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.preferences.fs = app.SamplingrateEditField.Value;
        end

        % Value changed function: Int1EditField
        function Int1EditFieldValueChanged(app, event)
            app.preferences.int(1) = app.Int1EditField.Value;            
        end

        % Value changed function: Int2EditField
        function Int2EditFieldValueChanged(app, event)
           app.preferences.int(2)  = app.Int2EditField.Value;            
        end

        % Value changed function: psth_binEditField
        function psth_binEditFieldValueChanged(app, event)
            app.preferences.psth_bin = app.psth_binEditField.Value;            
        end

        % Value changed function: NormalisedataCheckBox
        function NormalisedataCheckBoxValueChanged(app, event)
            app.preferences.norm = app.NormalisedataCheckBox.Value;            
        end

        % Value changed function: PlotaveragesCheckBox
        function PlotaveragesCheckBoxValueChanged(app, event)
            app.preferences.average = app.PlotaveragesCheckBox.Value;
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)
            app.animalID = strsplit(app.AnimalIDEditField.Value);            
        end

        % Value changed function: TTLEditField
        function TTLEditFieldValueChanged(app, event)
            app.TTL = app.TTLEditField.Value;
        end

        % Value changed function: PlottingmethodDropDown
        function PlottingmethodDropDownValueChanged(app, event)
            app.preferences.plottingMethod = app.PlottingmethodDropDown.Value;
        end

        % Value changed function: Wcx_win1EditField
        function Wcx_win1EditFieldValueChanged(app, event)
            app.preferences.Wcx_win(1) = app.Wcx_win1EditField.Value;            
        end

        % Value changed function: Wcx_win2EditField
        function Wcx_win2EditFieldValueChanged(app, event)
            app.preferences.Wcx_win(2) = app.Wcx_win2EditField.Value;
            
        end

        % Value changed function: ZscoretestBinsEditField
        function ZscoretestBinsEditFieldValueChanged(app, event)
            app.preferences.testBins = app.ZscoretestBinsEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Int1EditFieldLabel = uilabel(app.UIFigure);
            app.Int1EditFieldLabel.Text = 'Int(1)';
            app.Int1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Int1EditField.ValueChangedFcn = createCallbackFcn(app, @Int1EditFieldValueChanged, true);
            app.Int1EditField.Value = -5;
            app.Int2EditFieldLabel = uilabel(app.UIFigure);
            app.Int2EditFieldLabel.Text = 'Int(2)';
            app.Int2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Int2EditField.ValueChangedFcn = createCallbackFcn(app, @Int2EditFieldValueChanged, true);
            app.Int2EditField.Value = 5;
            app.psth_binEditFieldLabel = uilabel(app.UIFigure);
            app.psth_binEditFieldLabel.Text = 'psth_bin';
            app.psth_binEditField = uieditfield(app.UIFigure, 'numeric');
            app.psth_binEditField.ValueChangedFcn = createCallbackFcn(app, @psth_binEditFieldValueChanged, true);
            app.psth_binEditField.Value = 6000;
            app.NormalisedataCheckBox = uicheckbox(app.UIFigure);
            app.NormalisedataCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalisedataCheckBoxValueChanged, true);
            app.NormalisedataCheckBox.Text = 'Normalise data';
            app.NormalisedataCheckBox.Value = true;
            app.PlotaveragesCheckBox = uicheckbox(app.UIFigure);
            app.PlotaveragesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotaveragesCheckBoxValueChanged, true);
            app.PlotaveragesCheckBox.Text = 'Plot averages';
            app.PlotaveragesCheckBox.Value = true;
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.SamplingrateEditField.Value = 30000;
            app.PlottingmethodDropDownLabel = uilabel(app.UIFigure);
            app.PlottingmethodDropDownLabel.Text = 'Plotting method';
            app.PlottingmethodDropDown = uidropdown(app.UIFigure);
            app.PlottingmethodDropDown.Items = {'Zscore', 'PCA'};
            app.PlottingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottingmethodDropDownValueChanged, true);
            app.PlottingmethodDropDown.Value = 'Zscore';
            app.Wcx_win1EditFieldLabel = uilabel(app.UIFigure);
            app.Wcx_win1EditFieldLabel.Text = 'Wcx_win(1)';
            app.Wcx_win1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wcx_win1EditField.ValueChangedFcn = createCallbackFcn(app, @Wcx_win1EditFieldValueChanged, true);
            app.Wcx_win1EditField.Value = -5;
            app.Wcx_win2EditFieldLabel = uilabel(app.UIFigure);
            app.Wcx_win2EditFieldLabel.Text = 'Wcx_win(2)';
            app.Wcx_win2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wcx_win2EditField.ValueChangedFcn = createCallbackFcn(app, @Wcx_win2EditFieldValueChanged, true);
            app.Wcx_win2EditField.Value = 2;
            app.ZscoretestBinsEditFieldLabel = uilabel(app.UIFigure);
            app.ZscoretestBinsEditFieldLabel.Text = 'ZscoretestBins';
            app.ZscoretestBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZscoretestBinsEditField.ValueChangedFcn = createCallbackFcn(app, @ZscoretestBinsEditFieldValueChanged, true);
            app.ZscoretestBinsEditField.Value = 10;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.mainFolderEditFieldLabel = uilabel(app.UIFigure);
            app.mainFolderEditFieldLabel.Text = 'mainFolder';
            app.mainFolderEditField = uieditfield(app.UIFigure, 'text');
            app.mainFolderEditField.ValueChangedFcn = createCallbackFcn(app, @mainFolderEditFieldValueChanged, true);
            app.mainFolderEditField.Value = 'Z:\HajosLab\Dani\Magyar_Daniel\experiments\PFC_layers\Chrimson_stGtACR\2021_december\Kilosort_v2';
            app.AnimalIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.AnimalIDEditField.Value = 'MD098 MD099 MD101 MD108';
            app.TTLEditFieldLabel = uilabel(app.UIFigure);
            app.TTLEditFieldLabel.Text = 'TTL';
            app.TTLEditField = uieditfield(app.UIFigure, 'text');
            app.TTLEditField.ValueChangedFcn = createCallbackFcn(app, @TTLEditFieldValueChanged, true);
            app.TTLEditField.Value = 'BA_25_5Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsthExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end